classdef activestate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        TextArea_3        matlab.ui.control.TextArea
        TextArea_2        matlab.ui.control.TextArea
        TimeleftbeforealarmisraisedsecondsLabel  matlab.ui.control.Label
        SubmitButton      matlab.ui.control.StateButton
        DELButton         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        ActiveStateLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.EditFieldLabel.Visible = "off";
        app.EditField.Visible = "off";
        app.Button.Visible = "off";
        app.Button_2.Visible = "off";
        app.Button_3.Visible = "off";
        app.Button_4.Visible = "off";
        app.Button_5.Visible = "off";
        app.Button_6.Visible = "off";
        app.Button_7.Visible = "off";
        app.Button_8.Visible = "off";
        app.Button_9.Visible = "off";
        app.Button_10.Visible = "off";
        app.Button_11.Visible = "off";
        app.Button_12.Visible = "off";
        app.DELButton.Visible = "off";
        app.SubmitButton.Visible = "off";
        app.TimeleftbeforealarmisraisedsecondsLabel.Visible = "off";
        app.TextArea_2.Visible = "off";
        app.TextArea_3.Visible = "off";
        app.TextArea_3.Value = {''};
        movegui(app.UIFigure, 'center');
        device = evalin('base','device');
        timer = evalin('base','currentTimer');
        STATE = 1;
        flush(device);
        write(device,1,"uint8");
        while timer > -1
            S5 = [' ',num2str(timer)];
            S1 = 'System will be placed into "Active state" in ';
            S2 = strcat(S1,S5);
            S3 = ' seconds. Make sure all entry points (door & window) are properly closed. Press the "push" button to unlock the door.';
            S4 = strcat(S2,S3);
            app.TextArea.Value = S4;
            timer = timer - 1;
            pause(1);
        end
        entryP = 'N'; % not any of the options
        runonce = 1;
        while entryP ~= 'C' 
            entryP = read(device,1,"char");
            if entryP == 'O'&& runonce % runonce set to zero so it only displays once
                app.TextArea.Value = {'The door is not properly closed. Waiting for user to close entry point before going into Active state...'};
                runonce = 0;
            elseif entryP == 'Z' && runonce
                app.TextArea.Value = {'The window is not properly closed. Waiting for user to close entry point before going into Active state...'};
                runonce = 0;
            elseif entryP == 'T'
                app.TextArea.Value = {'All entry points are closed. Activating System in 10 seconds...'};
            end
        end
         flush(device);

         while STATE == 1 % Active state
            flush(device);
            app.TextArea.Value = {'SYSTEM IS ACTIVE'};
            sensor = read(device,1,"char"); % reading from arduino
            if sensor == 'M' % If movement has been detected
                app.TextArea.Value = {'Motion detected outside the door. Running facial recognition...'};
                pause(3);
                detection = streamingFaceRecognition(8,0.5,3); % Running Facial Recognition.
                if detection == 1 % Person identified
                    app.TextArea.Value = {'Person identified. Unlocking door...'};
                    write(device,1,"uint8");
                    pause(2);
                else % Person not identified
                     write(device,0,"uint8");
                     app.TextArea.Value = {'Person not identified'};
                     pause(2);
                end
                sensor = read(device,1,"char");
            end
            if sensor == 'D'   % Door opened when unlocked
                flush(device);
                app.TextArea.Value = {'Please enter your PIN'};
                app.Button.Visible = "on";
                app.Button_2.Visible = "on";
                app.Button_3.Visible = "on";
                app.Button_4.Visible = "on";
                app.Button_5.Visible = "on";
                app.Button_6.Visible = "on";
                app.Button_7.Visible = "on";
                app.Button_8.Visible = "on";
                app.Button_9.Visible = "on";
                app.Button_10.Visible = "on";
                app.Button_11.Visible = "on";
                app.Button_12.Visible = "on";
                app.DELButton.Visible = "on";
                app.SubmitButton.Visible = "on";
                app.TimeleftbeforealarmisraisedsecondsLabel.Visible = "on";
                app.TextArea_2.Visible = "on";
                app.TextArea_3.Visible = "on";
                timer = evalin('base','currentTimer');
                while timer > -1
                    app.TextArea_2.Value = num2str(timer);
                    timer = timer - 1;
                    pause(1);
                end
                app.Button.Visible = "off";
                app.Button_2.Visible = "off";
                app.Button_3.Visible = "off";
                app.Button_4.Visible = "off";
                app.Button_5.Visible = "off";
                app.Button_6.Visible = "off";
                app.Button_7.Visible = "off";
                app.Button_8.Visible = "off";
                app.Button_9.Visible = "off";
                app.Button_10.Visible = "off";
                app.Button_11.Visible = "off";
                app.Button_12.Visible = "off";
                app.DELButton.Visible = "off";
                app.SubmitButton.Visible = "off";
                app.TimeleftbeforealarmisraisedsecondsLabel.Visible = "off";
                app.TextArea_2.Visible = "off";
                app.TextArea_3.Visible = "off";
                write(device,1,"int8");
                app.TextArea.Value = {'ALARM IS SOUNDING. CALLING EMERGENCY SERVICES...'};
                pause(2)
                STATE = 2;
                close all force;
                run checkpinforalarm.mlapp;
            elseif sensor == 'L' % Door opened while solenoid is extended (Door has been broken)
                app.TextArea.Value = {'BREAK IN THROUGH THE DOOR. CALLING EMERGENCY SERVICES...'};
                sensor = read(device,1,"char");
                close all force;
                run mainmenu.mlapp;
                STATE = 2;
            elseif sensor == 'W'   % Window opened
                app.TextArea.Value = {'BREAK IN THROUGH THE WINDOW. CALLING EMERGENCY SERVICES...'};
                sensor = read(device,1,"char"); % 20min have passed (from arduino)
                close all force;
                run mainmenu.mlapp;
                STATE = 2;
            end      
         end        
        end

        % Callback function
        function LEDONButtonPushed(app, event)

        end

        % Callback function
        function LEDOFFButtonPushed(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '1';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '2';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '3';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '4';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '5';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '6';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '7';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '8';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '9';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '*';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '0';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            value = app.TextArea_3.Value;
            value2 = '#';
            app.TextArea_3.Value = strcat(value,value2);
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            value = app.TextArea_3.Value;
            value2 = value(end);
            app.TextArea_3.Value = erase(value,value2);
        end

        % Value changed function: SubmitButton
        function SubmitButtonValueChanged(app, event)
            device = evalin('base','device');
            value = app.TextArea_3.Value;
            data = evalin('base','currentPIN');
            compare = strcmp(value,data);
            if compare == 1
                app.Button.Visible = "off";
                app.Button_2.Visible = "off";
                app.Button_3.Visible = "off";
                app.Button_4.Visible = "off";
                app.Button_5.Visible = "off";
                app.Button_6.Visible = "off";
                app.Button_7.Visible = "off";
                app.Button_8.Visible = "off";
                app.Button_9.Visible = "off";
                app.Button_10.Visible = "off";
                app.Button_11.Visible = "off";
                app.Button_12.Visible = "off";
                app.DELButton.Visible = "off";
                app.SubmitButton.Visible = "off";
                app.TimeleftbeforealarmisraisedsecondsLabel.Visible = "off";
                app.TextArea_2.Visible = "off";
                app.TextArea_3.Visible = "off";
                app.TextArea.Value = {'Alarm deactivated. Returning to idle state...'};
                write(device,2,"int8");
                pause(3);
                close all force;
                run mainmenu.mlapp;
            elseif compare == 0
                app.TextArea_3.Value = {'ERROR: Incorrect PIN'};
                pause(1);
                app.TextArea_3.Value = {''};
                app.EditField.Value = app.EditField.Value + 1;
                if app.EditField.Value == 3
                    app.TextArea.Value = {'EXCEEDED NUMBER OF ATTEMPTS'};
                    app.Button.Visible = "off";
                    app.Button_2.Visible = "off";
                    app.Button_3.Visible = "off";
                    app.Button_4.Visible = "off";
                    app.Button_5.Visible = "off";
                    app.Button_6.Visible = "off";
                    app.Button_7.Visible = "off";
                    app.Button_8.Visible = "off";
                    app.Button_9.Visible = "off";
                    app.Button_10.Visible = "off";
                    app.Button_11.Visible = "off";
                    app.Button_12.Visible = "off";
                    app.DELButton.Visible = "off";
                    app.SubmitButton.Visible = "off";
                    app.TimeleftbeforealarmisraisedsecondsLabel.Visible = "off";
                    app.TextArea_2.Visible = "off";
                    app.TextArea_3.Visible = "off";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ActiveStateLabel = uilabel(app.UIFigure);
            app.ActiveStateLabel.Text = 'Active State';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.DELButton = uibutton(app.UIFigure, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.SubmitButton = uibutton(app.UIFigure, 'state');
            app.SubmitButton.ValueChangedFcn = createCallbackFcn(app, @SubmitButtonValueChanged, true);
            app.SubmitButton.Text = 'Submit';
            app.TimeleftbeforealarmisraisedsecondsLabel = uilabel(app.UIFigure);
            app.TimeleftbeforealarmisraisedsecondsLabel.Text = 'Time left before alarm is raised (seconds)';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activestate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end