classdef changetimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ReturntomainmenuButton  matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        Slider                  matlab.ui.control.Slider
        SubmitButton            matlab.ui.control.StateButton
        PleaseInputthenumberofsecondsTimermustbe1060EditField  matlab.ui.control.NumericEditField
        PleaseInputthenumberofsecondsTimermustbe1060EditFieldLabel  matlab.ui.control.Label
        ChangeTimerLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            write(evalin('base','device'),4,"uint8");
            movegui(app.UIFigure, 'center');
            data = evalin('base','currentTimer');
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value = data;
            app.TextArea.Visible = "off";
        end

        % Callback function
        function ButtonPushed(app, event)
            value = app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value;
            value2 = '1';
            value3 = strcat(value,value2);
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value = str2num(value3);
        end

        % Callback function
        function DELButtonPushed(app, event)
           app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value = 0;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value = changingValue;
        end

        % Value changed function: SubmitButton
        function SubmitButtonValueChanged(app, event)
            value = app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value;
            if mod(value,1) ~= 0
                value = round(value);
            end
            assignin('base','currentTimer',value);
            write(evalin('base','device'),value,"uint8");
            app.TextArea.Value = {'SUCCESS: New Timer Set!'};
            app.TextArea.Visible = "on";
            pause(5);
            close all force;
            run mainmenu.mlapp;
        end

        % Button pushed function: ReturntomainmenuButton
        function ReturntomainmenuButtonPushed(app, event)
            close all force;
            run mainmenu.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChangeTimerLabel = uilabel(app.UIFigure);
            app.ChangeTimerLabel.Text = 'Change Timer';
            app.PleaseInputthenumberofsecondsTimermustbe1060EditFieldLabel = uilabel(app.UIFigure);
            app.PleaseInputthenumberofsecondsTimermustbe1060EditFieldLabel.Text = 'Please adjust the slider and press the ''Submit'' button to change the timer';
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField = uieditfield(app.UIFigure, 'numeric');
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Limits = [10 59];
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.ValueDisplayFormat = '%.0f';
            app.PleaseInputthenumberofsecondsTimermustbe1060EditField.Value = 10;
            app.SubmitButton = uibutton(app.UIFigure, 'state');
            app.SubmitButton.ValueChangedFcn = createCallbackFcn(app, @SubmitButtonValueChanged, true);
            app.SubmitButton.Text = 'Submit';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [10 60];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 10;
            app.TextArea = uitextarea(app.UIFigure);
            app.ReturntomainmenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainmenuButtonPushed, true);
            app.ReturntomainmenuButton.Text = 'Return to main menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = changetimer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end