classdef checkpinforresetdatabase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ReturntomainmenuButton  matlab.ui.control.Button
        PressthesubmitbuttontoproceedLabel  matlab.ui.control.Label
        SubmitButton            matlab.ui.control.StateButton
        DELButton               matlab.ui.control.Button
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Button_10               matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        PleaseenteryourPINtocontinuewiththeprocedureLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            app.TextArea.Value = {''};
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.TextArea.Value;
            value2 = '1';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '2';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '3';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '4';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '5';
            app.TextArea.Value = strcat(value,value2); 
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '6';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '7';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '8';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '9';
            app.TextArea.Value = strcat(value,value2); 
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '*';
            app.TextArea.Value = strcat(value,value2); 
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '0';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            value = app.TextArea.Value;
            value2 = '#';
            app.TextArea.Value = strcat(value,value2);
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            value = app.TextArea.Value;
            value2 = value(end);
            app.TextArea.Value = erase(value,value2);
        end

        % Value changed function: SubmitButton
        function SubmitButtonValueChanged(app, event)
            value = app.TextArea.Value;
            data = evalin('base','currentPIN');
            compare = strcmp(value,data);
            if compare == 1
                streamingFaceRecognition(8,0.5,1);
                close all force;
                run mainmenu.mlapp;

            else
                beep;
                app.TextArea.Value = {'ERROR: Incorrect PIN'};
                pause(2);
                app.TextArea.Value = {''};
            end
        end

        % Button pushed function: ReturntomainmenuButton
        function ReturntomainmenuButtonPushed(app, event)
            close all force;
            run mainmenu.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseenteryourPINtocontinuewiththeprocedureLabel = uilabel(app.UIFigure);
            app.PleaseenteryourPINtocontinuewiththeprocedureLabel.Text = 'Please enter your PIN to continue with the procedure';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.DELButton = uibutton(app.UIFigure, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.SubmitButton = uibutton(app.UIFigure, 'state');
            app.SubmitButton.ValueChangedFcn = createCallbackFcn(app, @SubmitButtonValueChanged, true);
            app.SubmitButton.Text = 'Submit';
            app.PressthesubmitbuttontoproceedLabel = uilabel(app.UIFigure);
            app.PressthesubmitbuttontoproceedLabel.Text = 'Press the ''submit'' button to proceed';
            app.ReturntomainmenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainmenuButtonPushed, true);
            app.ReturntomainmenuButton.Text = 'Return to main menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = checkpinforresetdatabase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end