classdef mainmenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExitProgramButton       matlab.ui.control.Button
        PleaseclickononeoftheoptionsbelowLabel  matlab.ui.control.Label
        ChangePINButton         matlab.ui.control.Button
        ChangeTimerButton       matlab.ui.control.Button
        ResetFacialRecognitionDatabaseAddNewImagesButton  matlab.ui.control.Button
        AddImagestoFacialRecognitionDatabaseButton  matlab.ui.control.Button
        ActiveStateButton       matlab.ui.control.Button
        MainMenuIdleStateLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: ChangePINButton
        function ChangePINButtonPushed(app, event)
            close all force;
            run checkpinforchangepin.mlapp;
        end

        % Button pushed function: ChangeTimerButton
        function ChangeTimerButtonPushed(app, event)
            close all force;
            run checkpinforchangetimer.mlapp;
        end

        % Button pushed function: ActiveStateButton
        function ActiveStateButtonPushed(app, event)
            close all force;
            run checkpinforactivestate.mlapp;
        end

        % Button pushed function: 
        % AddImagestoFacialRecognitionDatabaseButton
        function AddImagestoFacialRecognitionDatabaseButtonPushed(app, event)
           close all force;
           run checkpinforaddingface.mlapp;
        end

        % Button pushed function: 
        % ResetFacialRecognitionDatabaseAddNewImagesButton
        function ResetFacialRecognitionDatabaseAddNewImagesButtonPushed(app, event)
            close all force;
            run checkpinforresetdatabase.mlapp;
        end

        % Button pushed function: ExitProgramButton
        function ExitProgramButtonPushed(app, event)
            write(evalin('base','device'),6,"uint8");
            pause(1);
            close all force;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuIdleStateLabel = uilabel(app.UIFigure);
            app.MainMenuIdleStateLabel.Text = 'Main Menu (Idle State)';
            app.ActiveStateButton = uibutton(app.UIFigure, 'push');
            app.ActiveStateButton.ButtonPushedFcn = createCallbackFcn(app, @ActiveStateButtonPushed, true);
            app.ActiveStateButton.Text = 'Active State';
            app.AddImagestoFacialRecognitionDatabaseButton = uibutton(app.UIFigure, 'push');
            app.AddImagestoFacialRecognitionDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagestoFacialRecognitionDatabaseButtonPushed, true);
            app.AddImagestoFacialRecognitionDatabaseButton.Text = 'Add Images to Facial Recognition Database';
            app.ResetFacialRecognitionDatabaseAddNewImagesButton = uibutton(app.UIFigure, 'push');
            app.ResetFacialRecognitionDatabaseAddNewImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFacialRecognitionDatabaseAddNewImagesButtonPushed, true);
            app.ResetFacialRecognitionDatabaseAddNewImagesButton.Text = 'Reset Facial Recognition Database & Add New Images';
            app.ChangeTimerButton = uibutton(app.UIFigure, 'push');
            app.ChangeTimerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeTimerButtonPushed, true);
            app.ChangeTimerButton.Text = 'Change Timer';
            app.ChangePINButton = uibutton(app.UIFigure, 'push');
            app.ChangePINButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINButtonPushed, true);
            app.ChangePINButton.Text = 'Change PIN';
            app.PleaseclickononeoftheoptionsbelowLabel = uilabel(app.UIFigure);
            app.PleaseclickononeoftheoptionsbelowLabel.Text = 'Please click on one of the options below';
            app.ExitProgramButton = uibutton(app.UIFigure, 'push');
            app.ExitProgramButton.ButtonPushedFcn = createCallbackFcn(app, @ExitProgramButtonPushed, true);
            app.ExitProgramButton.Text = 'Exit Program';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainmenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end