classdef Task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HelpInfoButton                matlab.ui.control.Button
        ThermalSystemSimulationLabel  matlab.ui.control.Label
        ResetvaluestodefaultButton    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        AmbientTemperaturePanel       matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        Knob6                         matlab.ui.control.Knob
        Theta_AEditField              matlab.ui.control.NumericEditField
        ThermalCapacitancePanel       matlab.ui.container.Panel
        C2Label                       matlab.ui.control.Label
        C1Label                       matlab.ui.control.Label
        Knob5                         matlab.ui.control.Knob
        Knob4                         matlab.ui.control.Knob
        C2EditField                   matlab.ui.control.NumericEditField
        C1EditField                   matlab.ui.control.NumericEditField
        ThermalResistancePanel        matlab.ui.container.Panel
        R3Label                       matlab.ui.control.Label
        R2Label                       matlab.ui.control.Label
        R1Label                       matlab.ui.control.Label
        Knob3                         matlab.ui.control.Knob
        Knob2                         matlab.ui.control.Knob
        R3EditField                   matlab.ui.control.NumericEditField
        Knob                          matlab.ui.control.Knob
        R2EditField                   matlab.ui.control.NumericEditField
        R1EditField                   matlab.ui.control.NumericEditField
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: RunButton
        function Run(app, event)
          assignin('base','R1',app.R1EditField.Value)
          assignin('base','R2',app.R2EditField.Value)
          assignin('base','R3',app.R3EditField.Value)
          assignin('base','C1',app.C1EditField.Value)
          assignin('base','C2',app.C2EditField.Value)
          assignin('base','Theta_A',app.Theta_AEditField.Value)
          assignin('base','R12',((app.R1EditField.Value*app.R2EditField.Value)/(app.R1EditField.Value+app.R2EditField.Value)));     
          assignin('base','R23',((app.R2EditField.Value*app.R3EditField.Value)/(app.R2EditField.Value+app.R3EditField.Value)));
          out=sim('Model');
          plot(app.UIAxes, (out.ScopeData{1}.Values.Time)/60,out.ScopeData{1}.Values.Data(:,1));
          hold(app.UIAxes,'on');
          plot(app.UIAxes, (out.ScopeData{2}.Values.Time)/60,out.ScopeData{2}.Values.Data(:,1));
          hold(app.UIAxes,'off');
          legend(app.UIAxes, '\theta_2','\theta_1','Location','southeast');
          plot(app.UIAxes2, (out.ScopeData1{1}.Values.Time)/60,out.ScopeData1{1}.Values.Data(:,1));
        end

        % Value changed function: R1EditField
        function R1Changed(app, event)
            value = app.R1EditField.Value;
            app.Knob.Value = value;
        end

        % Callback function
        function R2Changing(app, event)

        end

        % Value changed function: Knob3
        function R3KnobChanged(app, event)
            value = app.Knob3.Value;
            app.R3EditField.Value = round(value,0);
        end

        % Button pushed function: ResetvaluestodefaultButton
        function Reset(app, event)
            app.R1EditField.Value = 10;
            app.R2EditField.Value = 10;
            app.R3EditField.Value = 10;
            app.C1EditField.Value = 50;
            app.C2EditField.Value = 60;
            app.Theta_AEditField.Value = 293.15;
            app.Knob.Value = 10;
            app.Knob2.Value = 10;
            app.Knob3.Value = 10;
            app.Knob4.Value = 50;
            app.Knob5.Value = 60;
            app.Knob6.Value = 293.15;
        end

        % Value changed function: Knob
        function R1KnobChanged(app, event)
            value = app.Knob.Value;
            app.R1EditField.Value = round(value,0);
        end

        % Value changed function: Knob2
        function R2KnobChanged(app, event)
            value = app.Knob2.Value;
            app.R2EditField.Value = round(value,0);
        end

        % Value changed function: Knob4
        function C1KnobChanged(app, event)
            value = app.Knob4.Value;
            app.C1EditField.Value = round(value,0);
        end

        % Value changed function: Knob5
        function C2KnobChanged(app, event)
            value = app.Knob5.Value;
            app.C2EditField.Value = round(value,0);
        end

        % Value changed function: Knob6
        function Theta_AKnobChanged(app, event)
            value = app.Knob6.Value;
            app.Theta_AEditField.Value = round(value,0);
        end

        % Value changed function: R2EditField
        function R2Changed(app, event)
            value = app.R2EditField.Value;
            app.Knob2.Value = value;
        end

        % Value changed function: R3EditField
        function R3Changed(app, event)
            value = app.R3EditField.Value;
            app.Knob3.Value = value;
        end

        % Value changed function: C1EditField
        function C1Changed(app, event)
            value = app.C1EditField.Value;
            app.Knob4.Value = value;
        end

        % Value changed function: C2EditField
        function C2Changed(app, event)
            value = app.C2EditField.Value;
            app.Knob5.Value = value;
        end

        % Value changed function: Theta_AEditField
        function Theta_AChanged(app, event)
            value = app.Theta_AEditField.Value;
            app.Knob6.Value = value;
        end

        % Button pushed function: HelpInfoButton
        function Help(app, event)
            msgbox('This GUI represesents a thermal system. The parameters of the system can be altered using either the knobs to select a value, or by entering the value in the fields underneath the knobs. To plot the response of the system and to see the effects of changing the parameters, press the "Run" button. To reset back to default values and to plot the default graph, press the "Reset values to default" button and then press the "Run" button.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '''\theta_1 & \theta_2'' Model')
            xlabel(app.UIAxes, 'Time (Minutes)')
            ylabel(app.UIAxes, 'Temperature (Kelvin)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '''Heat Flow into the System'' Model')
            xlabel(app.UIAxes2, 'Time (Minutes)')
            ylabel(app.UIAxes2, 'Q1 (Amplitude)')
            zlabel(app.UIAxes2, 'Z')
            app.ThermalResistancePanel = uipanel(app.UIFigure);
            app.ThermalResistancePanel.Title = 'Thermal Resistance';
            app.R1EditField = uieditfield(app.ThermalResistancePanel, 'numeric');
            app.R1EditField.Limits = [1 200];
            app.R1EditField.ValueChangedFcn = createCallbackFcn(app, @R1Changed, true);
            app.R1EditField.Value = 10;
            app.R2EditField = uieditfield(app.ThermalResistancePanel, 'numeric');
            app.R2EditField.Limits = [1 200];
            app.R2EditField.ValueChangedFcn = createCallbackFcn(app, @R2Changed, true);
            app.R2EditField.Value = 10;
            app.Knob = uiknob(app.ThermalResistancePanel, 'continuous');
            app.Knob.Limits = [1 200];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @R1KnobChanged, true);
            app.Knob.Value = 10;
            app.R3EditField = uieditfield(app.ThermalResistancePanel, 'numeric');
            app.R3EditField.Limits = [1 200];
            app.R3EditField.ValueChangedFcn = createCallbackFcn(app, @R3Changed, true);
            app.R3EditField.Value = 10;
            app.Knob2 = uiknob(app.ThermalResistancePanel, 'continuous');
            app.Knob2.Limits = [1 200];
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @R2KnobChanged, true);
            app.Knob2.Value = 10;
            app.Knob3 = uiknob(app.ThermalResistancePanel, 'continuous');
            app.Knob3.Limits = [1 200];
            app.Knob3.ValueChangedFcn = createCallbackFcn(app, @R3KnobChanged, true);
            app.Knob3.Value = 10;
            app.R1Label = uilabel(app.ThermalResistancePanel);
            app.R1Label.Text = 'R1';
            app.R2Label = uilabel(app.ThermalResistancePanel);
            app.R2Label.Text = 'R2';
            app.R3Label = uilabel(app.ThermalResistancePanel);
            app.R3Label.Text = 'R3';
            app.ThermalCapacitancePanel = uipanel(app.UIFigure);
            app.ThermalCapacitancePanel.Title = 'Thermal Capacitance';
            app.C1EditField = uieditfield(app.ThermalCapacitancePanel, 'numeric');
            app.C1EditField.Limits = [1 200];
            app.C1EditField.ValueChangedFcn = createCallbackFcn(app, @C1Changed, true);
            app.C1EditField.Value = 50;
            app.C2EditField = uieditfield(app.ThermalCapacitancePanel, 'numeric');
            app.C2EditField.Limits = [1 200];
            app.C2EditField.ValueChangedFcn = createCallbackFcn(app, @C2Changed, true);
            app.C2EditField.Value = 60;
            app.Knob4 = uiknob(app.ThermalCapacitancePanel, 'continuous');
            app.Knob4.Limits = [1 200];
            app.Knob4.ValueChangedFcn = createCallbackFcn(app, @C1KnobChanged, true);
            app.Knob4.Value = 50;
            app.Knob5 = uiknob(app.ThermalCapacitancePanel, 'continuous');
            app.Knob5.Limits = [1 200];
            app.Knob5.ValueChangedFcn = createCallbackFcn(app, @C2KnobChanged, true);
            app.Knob5.Value = 60;
            app.C1Label = uilabel(app.ThermalCapacitancePanel);
            app.C1Label.Text = 'C1';
            app.C2Label = uilabel(app.ThermalCapacitancePanel);
            app.C2Label.Text = 'C2';
            app.AmbientTemperaturePanel = uipanel(app.UIFigure);
            app.AmbientTemperaturePanel.Title = 'Ambient Temperature';
            app.Theta_AEditField = uieditfield(app.AmbientTemperaturePanel, 'numeric');
            app.Theta_AEditField.Limits = [0 320];
            app.Theta_AEditField.ValueChangedFcn = createCallbackFcn(app, @Theta_AChanged, true);
            app.Theta_AEditField.Value = 293.15;
            app.Knob6 = uiknob(app.AmbientTemperaturePanel, 'continuous');
            app.Knob6.Limits = [0 320];
            app.Knob6.ValueChangedFcn = createCallbackFcn(app, @Theta_AKnobChanged, true);
            app.Knob6.Value = 293.15;
            app.Label = uilabel(app.AmbientTemperaturePanel);
            app.Label.Text = '';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.ResetvaluestodefaultButton = uibutton(app.UIFigure, 'push');
            app.ResetvaluestodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetvaluestodefaultButton.Text = 'Reset values to default';
            app.ThermalSystemSimulationLabel = uilabel(app.UIFigure);
            app.ThermalSystemSimulationLabel.Text = 'Thermal System Simulation';
            app.HelpInfoButton = uibutton(app.UIFigure, 'push');
            app.HelpInfoButton.ButtonPushedFcn = createCallbackFcn(app, @Help, true);
            app.HelpInfoButton.Text = 'Help/Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end